classdef AnthocyaninApp_withchart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ResultLabel  matlab.ui.control.Label
        PredictBtn   matlab.ui.control.Button
        UploadBtn    matlab.ui.control.Button
        HistoryAxes  matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    % Properties that correspond to app components
    properties (Access = private)
        trainedNet
        uploadedImage
        minLabel
        maxLabel
        predictionHistory = []; % Store predicted values
        imageNames = {};        % Store file names
        lastImageName = ''     % Store last uploaded file name
    end

    methods (Access = private)

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
   modelData = load('CustomCNN_AnthocyaninNet.mat');
            app.trainedNet = modelData.trainedNet;
            app.minLabel = modelData.minLabel;
            app.maxLabel = modelData.maxLabel;
            app.ResultLabel.Text = 'Upload a flower image to begin.';
    

        end

        % Button pushed function: UploadBtn
        function UploadBtnButtonPushed(app, event)
           
   [file, path] = uigetfile({'*.jpg;*.png'}, 'Select Flower Image');
            if isequal(file, 0)
                app.ResultLabel.Text = 'No file selected.';
                return;
            end
            img = imread(fullfile(path, file));
            img = imresize(img, [128 128]);
            app.lastImageName = file;
            app.uploadedImage = img;
            imshow(img, 'Parent', app.UIAxes);
            app.ResultLabel.Text = 'Image loaded. Click Predict.';
     
        end

        % Button pushed function: PredictBtn
        function PredictBtnButtonPushed(app, event)
      if isempty(app.uploadedImage)
                app.ResultLabel.Text = 'Please upload an image first.';
                return;
            end

            % Resize and normalize input
            img = imresize(app.uploadedImage, [128 128]);
            img = im2single(img);
            imgReshaped = reshape(img, [128 128 3 1]);

            % Predict
            pred = predict(app.trainedNet, imgReshaped);

            % Denormalize and clamp to [minLabel, maxLabel]
            prediction = pred * (app.maxLabel - app.minLabel) + app.minLabel;
            prediction = max(min(prediction, app.maxLabel), app.minLabel);

            % Display result
            app.ResultLabel.Text = sprintf('Predicted: %.2f mg/100g', prediction);

            % Save to history
            if isprop(app, 'lastImageName') && ~isempty(app.lastImageName)
                name = app.lastImageName;
            else
                name = sprintf('Image %d', length(app.predictionHistory) + 1);
            end
            app.predictionHistory(end+1) = prediction;
            app.imageNames{end+1} = name;

            % Update history chart
            bar(app.HistoryAxes, categorical(app.imageNames), app.predictionHistory, ...
                'FaceColor', [0.2 0.5 0.8]);
            title(app.HistoryAxes, 'Prediction History');
            xlabel(app.HistoryAxes, 'Image');
            ylabel(app.HistoryAxes, 'Predicted Anthocyanin (mg/100g)');
            grid(app.HistoryAxes, 'on');
             

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HistoryAxes = uiaxes(app.UIFigure);
            title(app.HistoryAxes, 'Prediction History')
            xlabel(app.HistoryAxes, 'X')
            ylabel(app.HistoryAxes, 'Y')
            zlabel(app.HistoryAxes, 'Z')
            app.UploadBtn = uibutton(app.UIFigure, 'push');
            app.UploadBtn.ButtonPushedFcn = createCallbackFcn(app, @UploadBtnButtonPushed, true);
            app.UploadBtn.Text = 'Upload Image';
            app.PredictBtn = uibutton(app.UIFigure, 'push');
            app.PredictBtn.ButtonPushedFcn = createCallbackFcn(app, @PredictBtnButtonPushed, true);
            app.PredictBtn.Text = 'Predict';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Predicted -';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnthocyaninApp_withchart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end