classdef AnthocyaninApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ResultLabel  matlab.ui.control.Label
        PredictBtn   matlab.ui.control.Button
        UploadBtn    matlab.ui.control.Button
        HistoryAxes  matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        net
        minVals
        maxVals
        uploadedImage
        targetNames = ["Anthocyanin", "TPC", "TFC", "DPPH"];
        predictionMatrix = [];  % Each row: [Anthocyanin, TPC, TFC, DPPH]
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    model = load('MTAERNetPlusv2_Final.mat');
            app.net = model.trainedNet;
            app.minVals = model.minVals;
            app.maxVals = model.maxVals;
            app.ResultLabel.Text = "Upload a flower image to begin.";
             
        end

        % Button pushed function: UploadBtn
        function UploadBtnButtonPushed(app, event)
     [file, path] = uigetfile({'*.jpg;*.png'}, 'Select an Image');
            if isequal(file, 0)
                app.ResultLabel.Text = "No image selected.";
                return;
            end
            imgPath = fullfile(path, file);
            img = imread(imgPath);
            img = imresize(img, [224 224]);
            app.uploadedImage = im2single(img);
            imshow(img, 'Parent', app.UIAxes);
  
        end

        % Button pushed function: PredictBtn
        function PredictBtnButtonPushed(app, event)
if isempty(app.uploadedImage)
                app.ResultLabel.Text = "Please upload an image first.";
                return;
            end

            % Predict
            inputImg = reshape(app.uploadedImage, [224 224 3 1]);
            normPred = predict(app.net, inputImg);

            % Denormalize
            truePred = zeros(1, 4);
            for i = 1:4
                minV = app.minVals{1, "min_" + app.targetNames(i)};
                maxV = app.maxVals{1, "max_" + app.targetNames(i)};
                truePred(i) = normPred(i) * (maxV - minV) + minV;
            end

            % Display results
            results = ["Predicted Values:"];
            for i = 1:4
                results(end+1) = sprintf('%s: %.2f', app.targetNames(i), truePred(i));
            end
            app.ResultLabel.Text = results;
     

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HistoryAxes = uiaxes(app.UIFigure);
            title(app.HistoryAxes, 'Prediction History')
            xlabel(app.HistoryAxes, 'X')
            ylabel(app.HistoryAxes, 'Y')
            zlabel(app.HistoryAxes, 'Z')
            app.UploadBtn = uibutton(app.UIFigure, 'push');
            app.UploadBtn.ButtonPushedFcn = createCallbackFcn(app, @UploadBtnButtonPushed, true);
            app.UploadBtn.Text = 'Upload Image';
            app.PredictBtn = uibutton(app.UIFigure, 'push');
            app.PredictBtn.ButtonPushedFcn = createCallbackFcn(app, @PredictBtnButtonPushed, true);
            app.PredictBtn.Text = 'Predict';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Predicted -';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnthocyaninApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end